classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ObjectSegmentationTab         matlab.ui.container.Tab
        MaskSegmentasiPanel           matlab.ui.container.Panel
        FinalBinaryMask               matlab.ui.control.UIAxes
        ProcessButton_2               matlab.ui.control.Button
        LinelengthforsegmentationSpinner  matlab.ui.control.Spinner
        LinelengthforsegmentationSpinnerLabel  matlab.ui.control.Label
        RemoveframeobjectCheckBox     matlab.ui.control.CheckBox
        ImageSelectedforSegmentation  matlab.ui.control.Label
        ImageSelectedLabel_2          matlab.ui.control.Label
        SelectImageButtonforSegmentation  matlab.ui.control.Button
        StandardDeviationLoGSpinner   matlab.ui.control.Spinner
        StandardDeviationLoGSpinnerLabel  matlab.ui.control.Label
        OutputPanel                   matlab.ui.container.Panel
        OutputSegmentation            matlab.ui.control.UIAxes
        EdgePanel                     matlab.ui.container.Panel
        EdgeSegmentation              matlab.ui.control.UIAxes
        InputPanel                    matlab.ui.container.Panel
        InputSegmentation             matlab.ui.control.UIAxes
        SelectEdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        SelectEdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        LaneDetectionTab              matlab.ui.container.Tab
        ProcessButton                 matlab.ui.control.Button
        ThresholdSlider               matlab.ui.control.Slider
        ThresholdSliderLabel          matlab.ui.control.Label
        HoughPanel                    matlab.ui.container.Panel
        HoughImage                    matlab.ui.control.UIAxes
        ImageSelectedforHough         matlab.ui.control.Label
        ImageSelectedLabel            matlab.ui.control.Label
        SelectImageButton             matlab.ui.control.Button
        OutputPanel_2                 matlab.ui.container.Panel
        OutputforHough                matlab.ui.control.UIAxes
        InputPanel_2                  matlab.ui.container.Panel
        InputforHough                 matlab.ui.control.UIAxes
        LaneDetectionwithHoughTransformationLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageInput % citra input
        ImageInputHough
        EdgeImage
        StdLoG
        OutputImageSegmentation
        RemoveObject
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            guiFolder = fileparts(mfilename('fullpath')); 
            currentFolder = pwd; 
        
            if strcmp(currentFolder, guiFolder)
                addpath(fullfile(guiFolder, 'utils')); 
                addpath(fullfile(guiFolder, '..', 'img')); 
            elseif strcmp(currentFolder, fileparts(guiFolder))
                addpath(fullfile(guiFolder, 'utils')); 
                addpath(fullfile(currentFolder, 'img')); 
            else
                warning('Folder kerja tidak dikenali, path tidak ditambahkan!');
            end
        end

        % Button pushed function: SelectImageButtonforSegmentation
        function SelectImageButtonforSegmentationPushed(app, event)
            % Pilih file gambar menggunakan uigetfile
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp; *.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'});
    
            % Jika pengguna memilih file
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputSegmentation);
                % Baca gambar
                img = imread(fullfile(path, file));
                app.ImageSelectedforSegmentation.Text = file;
        
                % Tampilkan gambar di UIAxes
                imshow(img, 'Parent', app.InputSegmentation);
                app.ImageInput = img;
            end

            value = app.SelectEdgeDetectionAlgorithmDropDown.Value;
            
            switch value
                case 'Laplace'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = laplace(app.ImageInput);
                case 'Laplace of Gaussian'
                    app.StandardDeviationLoGSpinner.Visible = "on";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "on";
                    app.EdgeImage = laplaceOfGaussian(app.ImageInput,or(app.StandardDeviationLoGSpinner.Value,1.4));
                case 'Sobel'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = sobel_operator(app.ImageInput);
                case 'Prewitt'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = prewitt_operator(app.ImageInput);
                case 'Roberts'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = roberts(app.ImageInput);
                case 'Canny'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = canny_operator(app.ImageInput);
            end
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Pilih file gambar menggunakan uigetfile
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp; *.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'});
    
            % Jika pengguna memilih file
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputforHough);
                % Baca gambar
                img = imread(fullfile(path, file));
                app.ImageSelectedforHough.Text = file;
        
                % Tampilkan gambar di UIAxes
                imshow(img, 'Parent', app.InputforHough);
                app.ImageInputHough = img;
            end
        end

        % Value changed function: SelectEdgeDetectionAlgorithmDropDown
        function SelectEdgeDetectionAlgorithmDropDownValueChanged(app, event)
            value = app.SelectEdgeDetectionAlgorithmDropDown.Value;
            
            switch value
                case 'Laplace'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = laplace(app.ImageInput);
                case 'Laplace of Gaussian'
                    app.StandardDeviationLoGSpinner.Visible = "on";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "on";
                    app.EdgeImage = laplaceOfGaussian(app.ImageInput,or(app.StandardDeviationLoGSpinner.Value,1.4));
                case 'Sobel'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = sobel_operator(app.ImageInput);
                case 'Prewitt'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = prewitt_operator(app.ImageInput);
                case 'Roberts'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = roberts(app.ImageInput);
                case 'Canny'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = canny_operator(app.ImageInput);
            end
        end

        % Value changed function: RemoveframeobjectCheckBox
        function RemoveframeobjectCheckBoxValueChanged(app, event)
            value = app.RemoveframeobjectCheckBox.Value;
            app.RemoveObject = value;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Hough
            [H, ~, ~, ~, ~, final] = line_detection(app.ImageInputHough,app.ThresholdSlider.Value);
            imshow(final, 'Parent', app.OutputforHough);
            imshow(H, 'Parent', app.HoughImage);
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            imshow(app.EdgeImage, 'Parent', app.EdgeSegmentation); 
            [out, mask] = segmentation(app.EdgeImage,app.ImageInput,app.RemoveObject,double(app.LinelengthforsegmentationSpinner.Value));
            app.OutputImageSegmentation = out;
            imshow(app.OutputImageSegmentation, 'Parent', app.OutputSegmentation);
            imshow(mask, 'Parent', app.FinalBinaryMask)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObjectSegmentationTab = uitab(app.TabGroup);
            app.ObjectSegmentationTab.Title = 'Object Segmentation';
            app.SelectEdgeDetectionAlgorithmDropDownLabel = uilabel(app.ObjectSegmentationTab);
            app.SelectEdgeDetectionAlgorithmDropDownLabel.Text = 'Select Edge Detection Algorithm';
            app.SelectEdgeDetectionAlgorithmDropDown = uidropdown(app.ObjectSegmentationTab);
            app.SelectEdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'Laplace of Gaussian', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.SelectEdgeDetectionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEdgeDetectionAlgorithmDropDownValueChanged, true);
            app.SelectEdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            app.InputPanel = uipanel(app.ObjectSegmentationTab);
            app.InputPanel.Title = 'Input';
            app.InputSegmentation = uiaxes(app.InputPanel);
            app.EdgePanel = uipanel(app.ObjectSegmentationTab);
            app.EdgePanel.Title = 'Edge';
            app.EdgeSegmentation = uiaxes(app.EdgePanel);
            app.OutputPanel = uipanel(app.ObjectSegmentationTab);
            app.OutputPanel.Title = 'Output';
            app.OutputSegmentation = uiaxes(app.OutputPanel);
            app.StandardDeviationLoGSpinnerLabel = uilabel(app.ObjectSegmentationTab);
            app.StandardDeviationLoGSpinnerLabel.Text = 'Standard Deviation (LoG)';
            app.StandardDeviationLoGSpinner = uispinner(app.ObjectSegmentationTab);
            app.StandardDeviationLoGSpinner.Value = 1.4;
            app.SelectImageButtonforSegmentation = uibutton(app.ObjectSegmentationTab, 'push');
            app.SelectImageButtonforSegmentation.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonforSegmentationPushed, true);
            app.SelectImageButtonforSegmentation.Text = 'Select Image';
            app.ImageSelectedLabel_2 = uilabel(app.ObjectSegmentationTab);
            app.ImageSelectedLabel_2.Text = 'Image Selected: ';
            app.ImageSelectedforSegmentation = uilabel(app.ObjectSegmentationTab);
            app.ImageSelectedforSegmentation.Text = '-';
            app.RemoveframeobjectCheckBox = uicheckbox(app.ObjectSegmentationTab);
            app.RemoveframeobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveframeobjectCheckBoxValueChanged, true);
            app.RemoveframeobjectCheckBox.Text = 'Remove frame object';
            app.LinelengthforsegmentationSpinnerLabel = uilabel(app.ObjectSegmentationTab);
            app.LinelengthforsegmentationSpinnerLabel.Text = 'Line length for segmentation';
            app.LinelengthforsegmentationSpinner = uispinner(app.ObjectSegmentationTab);
            app.LinelengthforsegmentationSpinner.Value = 6;
            app.ProcessButton_2 = uibutton(app.ObjectSegmentationTab, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.MaskSegmentasiPanel = uipanel(app.ObjectSegmentationTab);
            app.MaskSegmentasiPanel.Title = 'Mask Segmentasi';
            app.FinalBinaryMask = uiaxes(app.MaskSegmentasiPanel);
            title(app.FinalBinaryMask, 'Title')
            app.LaneDetectionTab = uitab(app.TabGroup);
            app.LaneDetectionTab.Title = 'Lane Detection';
            app.LaneDetectionwithHoughTransformationLabel = uilabel(app.LaneDetectionTab);
            app.LaneDetectionwithHoughTransformationLabel.Text = 'Lane Detection with Hough Transformation';
            app.InputPanel_2 = uipanel(app.LaneDetectionTab);
            app.InputPanel_2.Title = 'Input';
            app.InputforHough = uiaxes(app.InputPanel_2);
            title(app.InputforHough, 'Title')
            app.OutputPanel_2 = uipanel(app.LaneDetectionTab);
            app.OutputPanel_2.Title = 'Output';
            app.OutputforHough = uiaxes(app.OutputPanel_2);
            title(app.OutputforHough, 'Title')
            app.SelectImageButton = uibutton(app.LaneDetectionTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImageSelectedLabel = uilabel(app.LaneDetectionTab);
            app.ImageSelectedLabel.Text = 'Image Selected: ';
            app.ImageSelectedforHough = uilabel(app.LaneDetectionTab);
            app.ImageSelectedforHough.Text = '-';
            app.HoughPanel = uipanel(app.LaneDetectionTab);
            app.HoughPanel.Title = 'Hough';
            app.HoughImage = uiaxes(app.HoughPanel);
            title(app.HoughImage, 'Title')
            app.ThresholdSliderLabel = uilabel(app.LaneDetectionTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.LaneDetectionTab);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.Value = 250;
            app.ProcessButton = uibutton(app.LaneDetectionTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end